classdef AC_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        Label_2                 matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        EditField_2Label        matlab.ui.control.Label
        EditField_2             matlab.ui.control.NumericEditField
        LeftCursorLabel         matlab.ui.control.Label
        RightCursorLabel        matlab.ui.control.Label
        WindowName              matlab.ui.control.Label
        FunctionsDropDownLabel  matlab.ui.control.Label
        FunctionsDropDown       matlab.ui.control.DropDown
        MaxLabel                matlab.ui.control.Label
        MinLabel                matlab.ui.control.Label
        MeanLabel               matlab.ui.control.Label
        RealPowerLabel          matlab.ui.control.Label
        PowerFactorLabel        matlab.ui.control.Label
        RMSLabel                matlab.ui.control.Label
        ReactivePowerLabel      matlab.ui.control.Label
        CalculationsLabel       matlab.ui.control.Label
        ResizeButton            matlab.ui.control.Button
        ZoomButton              matlab.ui.control.StateButton
        Label_3                 matlab.ui.control.Label
        EditField_Max           matlab.ui.control.NumericEditField
        EditField_4Label        matlab.ui.control.Label
        EditField_Min           matlab.ui.control.NumericEditField
        EditField_5Label        matlab.ui.control.Label
        EditField_Mean          matlab.ui.control.NumericEditField
        EditField_6Label        matlab.ui.control.Label
        EditField_RMS           matlab.ui.control.NumericEditField
        EditField_7Label        matlab.ui.control.Label
        EditField_PF            matlab.ui.control.NumericEditField
        EditField_8Label        matlab.ui.control.Label
        EditField_RealP         matlab.ui.control.NumericEditField
        EditField_9Label        matlab.ui.control.Label
        EditField_ReactiveP     matlab.ui.control.NumericEditField
        TextAreaLabel           matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp;     % Description
        allData;        % Matrix that holds all data
        columnHeaders;  % Units for each column
        headerInfo;     % File info
        channelNum;     % The channel window identifier        
    end
    
    methods (Access = private)
        
        function ZC = ZeroX(x,y)
            zci = @(v) find(v(:).*circshift(v(:), [-1 0]) <= 0);           % Returns Approximate Zero-Crossing Indices Of Argument Vector
            zxidx = zci(y);
            for k1 = 1:numel(zxidx)
                idxrng = max([1 zxidx(k1)-1]):min([zxidx(k1)+1 numel(y)]);
                xrng = x(idxrng);
                yrng = y(idxrng);
                ZC(k1) = interp1( yrng(:), xrng(:), 0, 'linear', 'extrap' );
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, allData, columnHeaders, headerInfo, windowTitle, num)
            
            % Store data sent from mainApp
            app.callingApp = mainapp;
            app.allData = allData; % Pushing all data to stuct
            app.columnHeaders = columnHeaders;
            app.headerInfo = headerInfo;
            app.channelNum = num;
            
            % Edit name of window    
            app.WindowName.Text = windowTitle;
            
            % Plot channel data
            plot(app.UIAxes, app.allData(1:300:end, 1), app.allData(1:300:end, num + 1));
            
            % Calculate basic values (min, max, etc.)
            app.EditField_Max.Value = max(allData(:,app.channelNum + 1));
            app.EditField_Min.Value = min(allData(:,app.channelNum + 1));
            app.EditField_Mean.Value = mean(allData(:,app.channelNum + 1));
            app.EditField_RMS.Value = rms(allData(:,app.channelNum + 1));
            
            % Implementation of power calculations
            channel_length = length(headerInfo);
            channel_length_floor = floor(channel_length);
            x = allData(:,1);
            y1 = allData(:,app.channelNum + 1);
            % if voltage, then y2 = channelnum + 1 + channel_length_floor
            % if current, then y2 = channelnum + 1 - channel_length_floor
            
            
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            value = app.ZoomButton.Value;
            if value == 1
                % Add a Magnification Box and an Overview tool.
                pan(app.UIAxes, 'on');
                zoom(app.UIAxes, 'on'); 
            elseif value == 0
                zoom(app.UIAxes,'off');
                pan(app.UIAxes, 'off');
            end
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.LeftCursorLabel = uilabel(app.UIFigure);
            app.LeftCursorLabel.Text = 'Left Cursor';
            app.RightCursorLabel = uilabel(app.UIFigure);
            app.RightCursorLabel.Text = 'Right Cursor';
            app.WindowName = uilabel(app.UIFigure);
            app.FunctionsDropDownLabel = uilabel(app.UIFigure);
            app.FunctionsDropDownLabel.Text = 'Functions';
            app.FunctionsDropDown = uidropdown(app.UIFigure);
            app.FunctionsDropDown.Items = {'Peak to Peak Ripple', 'Individual Harmonics', 'DC Content', 'Crest Factor', 'Modulation', 'Envelope', 'Waveform Frequency', 'Frequency Spectrum Analysis'};
            app.FunctionsDropDown.Value = 'Peak to Peak Ripple';
            app.CalculationsLabel = uilabel(app.UIFigure);
            app.CalculationsLabel.Text = 'Calculations';
            app.ResizeButton = uibutton(app.UIFigure, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.ZoomButton = uibutton(app.UIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'Max:';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = 'Min:';
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Text = 'Mean:';
            app.RealPowerLabel = uilabel(app.UIFigure);
            app.RealPowerLabel.Text = 'Real Power:';
            app.PowerFactorLabel = uilabel(app.UIFigure);
            app.PowerFactorLabel.Text = 'Power Factor:';
            app.RMSLabel = uilabel(app.UIFigure);
            app.RMSLabel.Text = 'RMS:';
            app.ReactivePowerLabel = uilabel(app.UIFigure);
            app.ReactivePowerLabel.Text = 'Reactive Power:';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.EditField_Max = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.EditField_Min = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '';
            app.EditField_Mean = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '';
            app.EditField_RMS = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = '';
            app.EditField_PF = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '';
            app.EditField_RealP = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = '';
            app.EditField_ReactiveP = uieditfield(app.UIFigure, 'numeric');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = AC_App(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end