classdef GDD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        WindowName                matlab.ui.control.Label
        CalculationsLabel         matlab.ui.control.Label
        ResizeButton              matlab.ui.control.Button
        ZoominButton              matlab.ui.control.StateButton
        ZoomoutButton             matlab.ui.control.Button
        MaxEditFieldLabel         matlab.ui.control.Label
        MaxEditField              matlab.ui.control.NumericEditField
        MinEditFieldLabel         matlab.ui.control.Label
        MinEditField              matlab.ui.control.NumericEditField
        MeanEditFieldLabel        matlab.ui.control.Label
        MeanEditField             matlab.ui.control.NumericEditField
        RMSEditFieldLabel         matlab.ui.control.Label
        RMSEditField              matlab.ui.control.NumericEditField
        LeftBoundEditField        matlab.ui.control.NumericEditField
        MaxLabel                  matlab.ui.control.Label
        MinLabel                  matlab.ui.control.Label
        XLimitEditField           matlab.ui.control.NumericEditField
        MeanLabel                 matlab.ui.control.Label
        RMSLabel                  matlab.ui.control.Label
        FunctionsDropDown_2Label  matlab.ui.control.Label
        FunctionsDropDown         matlab.ui.control.DropDown
        PeaktoPeakLabel           matlab.ui.control.Label
        P2P                       matlab.ui.control.NumericEditField
        P2PLabel                  matlab.ui.control.Label
        SpectrumAnalysisButton    matlab.ui.control.Button
        CrestFactorLabel          matlab.ui.control.Label
        CrestFactorLabel_3        matlab.ui.control.Label
        CrestFactorEditField      matlab.ui.control.NumericEditField
        SaveImgButton             matlab.ui.control.Button
        PropertiesButton          matlab.ui.control.Button
        YLimLeft                  matlab.ui.control.NumericEditField
        XLimLULabel               matlab.ui.control.Label
        YLimLULabel               matlab.ui.control.Label
        YLimRight                 matlab.ui.control.NumericEditField
        PlotLimitsLabel           matlab.ui.control.Label
        ThdLab                    matlab.ui.control.Label
        THDLabel                  matlab.ui.control.Label
        THDEditField              matlab.ui.control.NumericEditField
    end

 properties (Access = private)
        callingApp                  % Handle to PQAT app
        allData                     % Matrix that holds all data
        columnHeaders               % Vector with units for each data column
        headerInfo                  % File info, metadata
        channelNum                  % The channel window identifier
        signalType                  % Type of analysis AC/DC
        dataPlot                    % Stores plot
        initialXLim                 % Initial X-limits of plot
        initialYLim                 % Initial Y-limits of plot
        startIndex                  % Start index of windowed data
        stopIndex                   % Stop index of windowed data
        lBound                      % Left graph boundary
        rBound                      % Right graph boundary
        lastTimeVal                 % Last recorded time value of data
        samplingFreq                % Initially 100 kHz
        PropWindow                  % Handle to properties window
        currentData                 % Holds current data GUI is working with
        zeroLine                    % 0-reference line plot
        modulationIdentifier        % modulation identifier
        phaseWindow                 % Handle to phase GUI
        caclPowerWindow             % Handle to power GUI
        channelSelect               % Selected channel from PQAT window
        windowTitle                 % Title of window             
        rmsApp                      % RMS handle
        roundVal = 3                % Decimal places
        calcPowerInterrupt;         % Handle to power interrupt window
    end
    
    methods (Access = private)
        
        % Dropdown menu items
        function functionBox(app, signalType)
            % Display dropdown for ac or dc
            if signalType == 0             
                app.FunctionsDropDown.Items = {'Raw Waveform', 'Frequency Waveform', 'Waveform Modulation Upper', ...
                    'Waveform Modulation Lower', 'Plot Phases', 'Calc Power', 'RMS Moving-Window', 'Power Interrupt'};
                app.FunctionsDropDown.Value = 'Raw Waveform';
                app.SpectrumAnalysisButton.Visible = 'off'; 
            else
                app.FunctionsDropDown.Visible = 'off';
                app.RMSEditField.Visible = 'off';
                app.RMSEditFieldLabel.Visible = 'off';         
                app.RMSLabel.Visible = 'off';
                app.CrestFactorLabel.Visible = 'off';
                app.CrestFactorEditField.Visible = 'off';
                app.CrestFactorLabel_3.Visible = 'off';
                app.THDEditField.Visible = 'off';
                app.ThdLab.Visible = 'off';
                app.THDLabel.Visible = 'off';
            end  
        end
        
        % GUI-updates when x axis changes
        function xLimChange(app)
            % Update bound fields
            updateBounds(app);
                        
            % Get indices
            [outOfBounds] = getIndices(app);
            
            % Update calculation fields
            calcFieldsInit(app, outOfBounds);
        end
        
        % GUI-updates when y axis changes
        function yLimChange(app)
            app.YLimLeft.Value = round(app.UIAxes.YLim(1), app.roundVal);
            app.YLimRight.Value = round(app.UIAxes.YLim(2), app.roundVal);
        end
        
        % Initializes the plot of GUI
        function plotInit(app)
            % Plot data
            app.dataPlot = plot(app.UIAxes, app.allData(1:1:end, 1), app.allData(1:1:end, app.channelNum));

            % Set axis labels
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = app.columnHeaders(app.channelNum);
            
            % Find last time recorded
            sz = size(app.allData);
            lastVal = ceil(app.allData(sz, 1));
            app.lastTimeVal = app.allData(length(app.allData));
            
            % Set XLim
            app.UIAxes.XLim = [0 lastVal(1)]; 
            
            % Create XY axis listeners
            warning('off','MATLAB:structOnObject');
            addlistener(struct(app.UIAxes).Axes, 'XLim', 'PostSet', @(src, evnt) xLimChange(app));
            addlistener(struct(app.UIAxes).Axes, 'YLim', 'PostSet', @(src, evnt) yLimChange(app));
        end
        
        % Updates calculation fields
        function calcFieldsInit(app, outOfBounds)            
            % Initialize Calculation fields
            % DC & AC
            [maxValue, minValue, meanValue, rmsValue, P2PValue, crestFValue, thdValue] = calcMaxMinMeanRMS(app, outOfBounds);
            app.MaxEditField.Value = maxValue;
            app.MinEditField.Value = minValue;
            app.MeanEditField.Value = meanValue;
            app.RMSEditField.Value = rmsValue;
            app.P2P.Value = P2PValue;
            
            % AC
            if app.signalType == 0
                app.CrestFactorEditField.Value = crestFValue;
                app.THDEditField.Value = thdValue;
            end
        end
        
        % Updates bounds of plot
        function updateBounds(app)
            app.LeftBoundEditField.Value = round(app.UIAxes.XLim(1), app.roundVal);
            app.XLimitEditField.Value = round(app.UIAxes.XLim(2), app.roundVal);            
        end
        
        % Calculates and returns max, min and mean values
        function [maxValue, minValue, meanValue, rmsValue, P2PValue, crestFValue, thdValue] = calcMaxMinMeanRMS(app, outOfBounds)                        
            channelData = app.currentData;       % Data for current channel
            crestFValue = 0;
            rmsValue = 0;
            thdValue = 0;
            
            if outOfBounds == 1
                maxValue = 0;
                minValue = 0;
                meanValue = 0;
                rmsValue = 0;
                P2PValue = 0;
                crestFValue = 0;
            else
                maxValue = (max(channelData(app.startIndex:app.stopIndex)));
                minValue = min(channelData(app.startIndex:app.stopIndex));
                meanValue = mean(channelData(app.startIndex:app.stopIndex));
                P2PValue = maxValue - minValue;
                        
                if app.signalType == 0 && app.modulationIdentifier == 0
                    crestFValue = peak2rms(channelData(app.startIndex:app.stopIndex));
                    rmsValue = rms(channelData(app.startIndex:app.stopIndex));
                    thdValue = 100 * (10 ^ (thd(channelData(app.startIndex:app.stopIndex)) / 20));
                end
                
            end
        end
        
        % Get indices function
        function [outOfBounds] = getIndices(app)
            
            % Get bounds
            app.lBound = app.UIAxes.XLim(1);
            app.rBound = app.UIAxes.XLim(2);
            
            % Calculate the distance between the time value from bound and input data
            distance1 = abs(app.allData(:,1) - app.lBound);
            distance2 = abs(app.allData(:,1) - app.rBound);
            
            outOfBounds = 0;    % Out of bounds trigger
            
            % If one cursor exceed the lower bound, get the index of minimum time value of input data
            if app.lBound < min(app.allData(:,1)) && app.rBound <= max(app.allData(:,1)) ...
                    && app.rBound > min(app.allData(:,1))
                app.startIndex = find(app.allData(:,1) == min(app.allData(:,1))); 
                app.stopIndex = find(distance2 == min(distance2));
                
            % If one cursor exceed the upper bound, get the index of maximum time value of input data
            elseif app.rBound > max(app.allData(:,1)) && app.lBound >= min(app.allData(:,1)) ...
                    && app.lBound < max(app.allData(:,1))
                app.startIndex = find(distance1 == min(distance1));
                app.stopIndex = find(app.allData(:,1)== max(app.allData(:,1)));
                
            % If both cursor exceed the input data range, get the index of minimum and maximum time value 
            elseif app.lBound < min(app.allData(:,1)) && app.rBound > max(app.allData(:,1))
                app.startIndex = find(app.allData(:,1)== min(app.allData(:,1))); 
                app.stopIndex = find(app.allData(:,1)== max(app.allData(:,1)));
                
           % If both cursor are in the input data range, get the index of the time values that are 
           % closest to the cursor position
            elseif app.lBound >= min(app.allData(:,1)) && app.rBound <= max(app.allData(:,1))
                app.startIndex = find(distance1 == min(distance1));
                app.stopIndex = find(distance2 == min(distance2));
                
            % If both cursor exceed the upper bound 
            elseif app.lBound > max(app.allData(:,1)) && app.rBound > max(app.allData(:,1))
                outOfBounds = 1;
                
            % iI both cursor exceed the lower bound
            elseif app.lBound < min(app.allData(:,1)) && app.lBound < min(app.allData(:,1))
                outOfBounds = 1;
            end   
        end
        
        % Modifies labels to appropriate units
        function unitLabelInit(app)
            app.MaxLabel.Text = app.columnHeaders(app.channelNum);
            app.MinLabel.Text = app.columnHeaders(app.channelNum);
            app.MeanLabel.Text = app.columnHeaders(app.channelNum);        
            app.RMSLabel.Text = app.columnHeaders(app.channelNum);
            app.P2PLabel.Text = app.columnHeaders(app.channelNum);
            app.CrestFactorLabel.Text = app.columnHeaders(app.channelNum);
            app.ThdLab.Text = "%";
        end         
                
        % Modulation function
        function modulation(app, type)
                        
            % Hide fields
            modHideFields(app, 'off');
                      
            %Holds data of channel
            channelData = app.allData(:, app.channelNum); 
            timeData = app.allData(:, 1);
            
            len = length(channelData) - 1;
            % A zero-crossing point is where the sign of a mathematical function changes (positive to negative) 
            % Upward zero-crossings to nearest time step
            upZeroCrossing = find((channelData(1:len)) <= 0 & channelData(2:end) > 0);
            PeakDistance = upZeroCrossing(2)-upZeroCrossing(1); % distance between 2 peaks equals to distance between 2 zero-crossing points
            minPeakDistance = PeakDistance - 3; % 3 data points to create peak
            
            % Get upperEnvelope
            [upperEnvelope, lowerEnvelope] = envelope(channelData, minPeakDistance, 'peak');  
            
            if (type == 0)
                app.FunctionsDropDown.Items = {'Raw Waveform', 'Waveform Modulation Upper'};
                % Plot Envelope Upper
                plot(app.UIAxes, timeData, upperEnvelope);
                
                % Use envelope data
                app.currentData = upperEnvelope;
            else
                app.FunctionsDropDown.Items = {'Raw Waveform', 'Waveform Modulation Lower'};
                % Plot Envelope Lower
                plot(app.UIAxes, timeData, lowerEnvelope);
                
                % Use envelope data
                app.currentData = lowerEnvelope;
            end
            app.UIAxes.YLimMode = 'auto';
            
            % delete
            delete(app.dataPlot)
            delete(app.zeroLine);
            
            app.modulationIdentifier = 1;
            
            % Update fields
            [outOfBound] = getIndices(app);
            calcFieldsInit(app, outOfBound);
            yLimChange(app);
        end
        
        % Frequency waveform function
        function freqWaveform(app) 
            
            % Ask User For Plot type
            dataParameter = questdlg('Plot in dB?', ...
                'Plot Type', ...
                'Yes', 'No', 'Yes');
            
            % Channel data
            channelData = app.currentData; 

            Fs = app.samplingFreq;      % Sampling Frequency (Hz)
            
            % Get data for signal
            signal = channelData(app.startIndex:app.stopIndex); 
            signal = signal.*hanning(length(signal));
            
            % Setup signal variables
            L  = length(signal);                     % Length Of Signal
            Fn = Fs/2;                               % Nyquist Frequency  
            NFFT = 2^nextpow2(L);
            Fsignal = fft(signal, NFFT)/L;                 % Scaled FFT For Plotting
            
            result = abs(Fsignal);
            result = result(1:NFFT/2 + 1);
            
            Fv = linspace(0, 1, NFFT/2 + 1) * Fn;      % Frequency Vector For Plotting

            % Getting the fundamental frequency
            [~,fundaFreq] = max(abs(Fsignal));
            fundaFreq = Fv(fundaFreq);
            
            % Create figure
            figure('Name', 'Frequency Waveform', 'NumberTitle','off',...
                'Position',[540 180 942 576]);

            % Plot graph type
            if dataParameter == "Yes"
                semilogx(Fv, 20*log10(4 * result), 'DisplayName', string(app.windowTitle));
                ylabel('Amplitude (dB)', 'fontweight', 'bold');
            else
                 loglog(Fv, 4 * result, 'DisplayName', string(app.windowTitle));
                 %yLabelName = "Amplitude (" + app.columnHeaders(app.channelNum) + ")";
                 ylabel('Amplitude', 'fontweight', 'bold');
            end
            
            % Plot properties
            grid on;       
            xlabel('Frequency (Hz)', 'fontweight', 'bold');
            title('Frequency Waveform');
            legend('show');
            
            % Anotation window
            dim = [.16 .6 .3 .3];
            str = "Fundamental Freq. = " + string(fundaFreq) + " Hz";
            annotation('textbox',dim,'String',str,'FitBoxToText','on');   
        end
        
        % Hides fields
        function modHideFields(app, state)
            app.RMSEditField.Visible = state;
            app.RMSEditFieldLabel.Visible = state;
            app.RMSLabel.Visible = state;
            app.CrestFactorEditField.Visible = state;
            app.CrestFactorLabel_3.Visible = state;
            app.CrestFactorLabel.Visible = state;
            app.THDLabel.Visible = state;
            app.THDEditField.Visible = state;
            app.ThdLab.Visible = state;
        end
        
        % Calculate sampling frequency
        function calcSamplingfreq(app)
            timeData = app.allData(:, 1);
            period = mean(diff(timeData));
            app.samplingFreq = 1 / period;
        end
        
        % Plots moving-window of RMS
    end
    
    % Public Functions
    methods (Access = public)
        
        function updateProp(app, samplingFreq)
            app.samplingFreq = samplingFreq;

        end
    end    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, signalType, allData, columnHeaders, headerInfo, windowTitle, num, channelSelect, WindowPos)
            
            % Store data sent from mainApp
            app.callingApp = mainapp;
            app.allData = allData; % Pushing all data to stuct
            app.columnHeaders = columnHeaders;
            app.headerInfo = headerInfo;
            app.channelNum = num;
            app.signalType = signalType;
            app.currentData = app.allData(:, app.channelNum);
            app.modulationIdentifier = 0;
            app.channelSelect = channelSelect;
            app.UIFigure.Position = WindowPos;
            app.windowTitle = windowTitle;
            
            % Edit name of window    
            app.WindowName.Text = windowTitle;
            app.UIFigure.Name = "Channel " + channelSelect +": " + windowTitle;

% -------------------DebugMode-------------------------------
%             app.signalType = 0;
%             if app.signalType == 0
%                 dataFile = 'C:\Users\rasmu\Downloads\AC_SS_Example\Extract_AC_SS.txt';
%             else 
%                 dataFile = "C:\Users\rasmu\Downloads\DC_SS_Example\Extract_DC_SS.txt";
%             end
%             % Read in all data
%             importedData = importdata(dataFile);
%                          
%             % Store data app
%             app.allData = importedData.data();
%             app.headerInfo = importedData.textdata;
%             try
%                 app.columnHeaders = importedData.colheaders;
%             catch
%                 app.columnHeaders = strsplit(cell2mat(app.headerInfo(7)));
%                 
%             end
%             app.channelSelect = 2;
%             app.modulationIdentifier = 0;
%             app.channelNum = 2;
%             app.currentData = app.allData(:, app.channelNum);
%             app.windowTitle = "test";
% --------------------------------------------------------------

% ---------------------UNUSED-----------------------------------
            app.PropertiesButton.Visible = 'off';
% --------------------------------------------------------------

            % Get sampling freq
            calcSamplingfreq(app);

            %Initialize Window
            plotInit(app);
            
            % Initialize function drop-down menu for AC or DC
            functionBox(app, app.signalType);
            
            % Initialize Calculation fields
            [outOfBound] = getIndices(app);
            calcFieldsInit(app, outOfBound);
            app.initialXLim = [app.lBound app.rBound];
            app.initialYLim = app.UIAxes.YLim;
            
            app.YLimLeft.Value = app.initialYLim(1);
            app.YLimRight.Value = app.initialYLim(2);
            
            % Plot zero-Line
            if app.signalType == 0
                hold(app.UIAxes, 'on');
                app.zeroLine = plot(app.UIAxes, [0 app.lastTimeVal], [0 0], 'k', 'LineWidth', 0.25);
            end
            
            % initialize units
            unitLabelInit(app);
            
            % Initialize Bound fields
            updateBounds(app);
        end

        % Value changed function: ZoominButton
        function ZoominButtonValueChanged(app, event)
            value = app.ZoominButton.Value;
            if value == 1
                app.ZoomoutButton.Enable = 'off';
                app.ResizeButton.Enable = 'off';
                pause(0.5)
                pan(app.UIAxes, 'on');
                zoom(app.UIAxes, 'on');
            else
                pan(app.UIAxes, 'off');
                zoom(app.UIAxes, 'off');
                app.ZoomoutButton.Enable = 'on';
                app.ResizeButton.Enable = 'on';
            end
            
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            app.UIAxes.XLim = app.initialXLim;
            app.UIAxes.YLim = app.initialYLim;
            app.YLimLeft.Value = round(app.UIAxes.YLim(1), app.roundVal);
            app.YLimRight.Value = round(app.UIAxes.YLim(2), app.roundVal);
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
                zoom(app.UIAxes, .75);  %zoom out by a factor of .75
                
                % Update Y Boxes
                app.YLimLeft.Value = round(app.UIAxes.YLim(1), app.roundVal);
                app.YLimRight.Value = round(app.UIAxes.YLim(2), app.roundVal);
        end

        % Value changed function: FunctionsDropDown
        function FunctionsDropDownValueChanged(app, event)
            selectedFunction = app.FunctionsDropDown.Value;                % Event trigger value
                        
            switch selectedFunction
                case 'Waveform Modulation Upper'
                    modulation(app, 0);
                case 'Waveform Modulation Lower'
                    modulation(app, 1);
                case 'Frequency Waveform'                   
                    freqWaveform(app);
                    app.FunctionsDropDown.Value = 'Raw Waveform';
                case 'Plot Phases'
                    app.phaseWindow = PhaseGUI(app, app.allData, app.columnHeaders, app.headerInfo, app.startIndex, app.stopIndex);
                    app.FunctionsDropDown.Value = 'Raw Waveform';
                case 'Calc Power'
                    app.caclPowerWindow = PowerGUI(app, app.allData, app.columnHeaders, app.headerInfo, app.startIndex, app.stopIndex, app.samplingFreq);
                    app.FunctionsDropDown.Value = 'Raw Waveform';
                case 'RMS Moving-Window'
                    app.rmsApp = getMovingRmsGUI(app, app.allData(:, app.channelNum));
                    app.FunctionsDropDown.Value = 'Raw Waveform';
                case 'Power Interrupt'
                    app.calcPowerInterrupt = powerInt(app, app.allData, app.columnHeaders, app.headerInfo, app.startIndex, app.stopIndex, app.samplingFreq);
                    app.FunctionsDropDown.Value = 'Raw Waveform';
                otherwise
                    modHideFields(app, 'on');
                    app.modulationIdentifier = 0;
                    app.currentData = app.allData(:, app.channelNum);
                    
                    % Re-plot original
                    cla(app.UIAxes);
                    app.dataPlot = plot(app.UIAxes, app.allData(1:end, 1), app.allData(1:end, app.channelNum));
                    app.UIAxes.YLimMode = 'auto';
                    app.UIAxes.XLim = app.initialXLim;
                    app.zeroLine = plot(app.UIAxes, [0 app.lastTimeVal], [0 0], 'k', 'LineWidth', 0.25);
                    app.currentData = app.allData(:, app.channelNum);
                    yLimChange(app);
                    functionBox(app, app.signalType);
            end
            
        end

        % Button pushed function: SpectrumAnalysisButton
        function SpectrumAnalysisButtonPushed(app, event)
            % Ask User For Plot type
            dataParameter = questdlg('Log or Linear?', ...
                'Plot Type', ...
                'Log', 'Linear', 'Log');
            
            % Holds data of channel
            channelData = app.allData(:, app.channelNum); 
            
            % The indecis; start -> stop
            startIndx = app.startIndex;
            stopIndx = app.stopIndex;
            
            % Init
            data = channelData(startIndx:stopIndx);
            data = data - mean(data);               % Remove DC offset to see the frequency component
            dataLength = length(data);
            NFFT = 2^nextpow2(dataLength);          % Transform length
            Fs = app.samplingFreq;
            
            % Apply hanning window  
            signal_HannWnd = data.*hanning(dataLength);         
            
            % Calculate FFT
            signaldft_HannWnd = fft(signal_HannWnd,NFFT)/dataLength;                
            result = abs(signaldft_HannWnd);                % get rid of complex value      
            result = result (1:NFFT/2 + 1);                 % single-side band spectrum
            
            % create frequency axis
            f = Fs/2*linspace(0,1,NFFT/2 + 1);                
            
            % Create Figure
            figure('Name', 'Frequency Spectrum Analysis with Hanning Window', 'NumberTitle','off',...
                'Position',[540 180 942 576]);
            
            % Handle Response
            switch dataParameter
                case 'Log' 
                    loglog(f,result * 4, 'DisplayName', string(app.windowTitle))
                case 'Linear'
                    plot(f,result * 4, 'DisplayName', string(app.windowTitle));
            end
            
            % Plot properties
            xlabel('Frequency (Hz)', 'FontWeight','bold'); 
            ylabel('Amplitude', 'FontWeight','bold');
            title('Spectrum Analysis', 'FontWeight','bold'); 
            grid on;
            axis 'auto'
            legend('show');
            
        end

        % Button pushed function: SaveImgButton
        function SaveImgButtonPushed(app, event)
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes);
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            try
                filter = {'*.png'};
                [file, path] = uiputfile(filter);
                fileName = [path, file];
                saveas(fig, fileName, 'png');
            catch
                % Suppress
            end
            delete(fig);
            
        end

        % Button pushed function: PropertiesButton
        function PropertiesButtonPushed(app, event)
            app.propWindow = PropWindow(app, app.samplingFreq);
            app.PropertiesButton.Enable = 'off';
        end

        % Value changed function: LeftBoundEditField
        function LeftBoundEditFieldValueChanged(app, event)
            leftLim = app.LeftBoundEditField.Value;
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.XLim = [leftLim app.XLimitEditField.Value];
        end

        % Value changed function: XLimitEditField
        function XLimitEditFieldValueChanged(app, event)
            rightLim = app.XLimitEditField.Value;
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.XLim = [app.LeftBoundEditField.Value rightLim];
        end

        % Value changed function: YLimLeft
        function YLimLeftValueChanged(app, event)
            leftLim = app.YLimLeft.Value;
            rightLim = app.YLimRight.Value;
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.YLim = [leftLim rightLim];
        end

        % Value changed function: YLimRight
        function YLimRightValueChanged(app, event)
            rightLim = app.YLimRight.Value;
            leftLim = app.YLimLeft.Value;
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.YLim = [leftLim rightLim];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Re-Enable button
            try
                if (app.channelSelect == 1)
                    app.callingApp.Channel1DropDown.Enable = 'on';
                    app.callingApp.Channel1DropDown.Value = '<select>';
                elseif (app.channelSelect == 2)
                    app.callingApp.Channel2DropDown.Enable = 'on';
                    app.callingApp.Channel2DropDown.Value = '<select>';
                elseif (app.channelSelect == 3)
                    app.callingApp.Channel3DropDown.Enable = 'on';
                    app.callingApp.Channel3DropDown.Value = '<select>';
                elseif (app.channelSelect == 4)
                    app.callingApp.Channel4DropDown.Enable = 'on';
                    app.callingApp.Channel4DropDown.Value = '<select>';
                elseif (app.channelSelect == 5)
                    app.callingApp.Channel5DropDown.Enable = 'on';
                    app.callingApp.Channel5DropDown.Value = '<select>';
                else
                    app.callingApp.Channel6DropDown.Enable = 'on'; 
                    app.callingApp.Channel6DropDown.Value = '<select>';
                end
            catch
            end
%             % Delete app
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.WindowName = uilabel(app.UIFigure);
            app.CalculationsLabel = uilabel(app.UIFigure);
            app.CalculationsLabel.Text = 'Calculations';
            app.ResizeButton = uibutton(app.UIFigure, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.ZoominButton = uibutton(app.UIFigure, 'state');
            app.ZoominButton.ValueChangedFcn = createCallbackFcn(app, @ZoominButtonValueChanged, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ZoomoutButton = uibutton(app.UIFigure, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max:';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.ValueDisplayFormat = '%.3f';
            app.MaxEditField.Editable = 'off';
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min:';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField.ValueDisplayFormat = '%.3f';
            app.MinEditField.Editable = 'off';
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean:';
            app.MeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanEditField.ValueDisplayFormat = '%.3f';
            app.MeanEditField.Editable = 'off';
            app.RMSEditFieldLabel = uilabel(app.UIFigure);
            app.RMSEditFieldLabel.Text = 'RMS:';
            app.RMSEditField = uieditfield(app.UIFigure, 'numeric');
            app.RMSEditField.ValueDisplayFormat = '%.3f';
            app.RMSEditField.Editable = 'off';
            app.LeftBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftBoundEditField.ValueDisplayFormat = '%.3f';
            app.LeftBoundEditField.ValueChangedFcn = createCallbackFcn(app, @LeftBoundEditFieldValueChanged, true);
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'U';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = 'U';
            app.XLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.XLimitEditField.ValueDisplayFormat = '%.3f';
            app.XLimitEditField.ValueChangedFcn = createCallbackFcn(app, @XLimitEditFieldValueChanged, true);
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Text = 'U';
            app.RMSLabel = uilabel(app.UIFigure);
            app.RMSLabel.Text = 'U';
            app.FunctionsDropDown_2Label = uilabel(app.UIFigure);
            app.FunctionsDropDown_2Label.Text = 'Functions';
            app.FunctionsDropDown = uidropdown(app.UIFigure);
            app.FunctionsDropDown.Items = {};
            app.FunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionsDropDownValueChanged, true);
            app.FunctionsDropDown.Value = {};
            app.PeaktoPeakLabel = uilabel(app.UIFigure);
            app.PeaktoPeakLabel.Text = 'Peak-to-Peak:';
            app.P2P = uieditfield(app.UIFigure, 'numeric');
            app.P2P.ValueDisplayFormat = '%.3f';
            app.P2P.Editable = 'off';
            app.P2PLabel = uilabel(app.UIFigure);
            app.P2PLabel.Text = 'U';
            app.SpectrumAnalysisButton = uibutton(app.UIFigure, 'push');
            app.SpectrumAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumAnalysisButtonPushed, true);
            app.SpectrumAnalysisButton.Text = 'Spectrum Analysis';
            app.CrestFactorLabel = uilabel(app.UIFigure);
            app.CrestFactorLabel.Text = 'U';
            app.CrestFactorLabel_3 = uilabel(app.UIFigure);
            app.CrestFactorLabel_3.Text = 'Crest Factor:';
            app.CrestFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CrestFactorEditField.ValueDisplayFormat = '%.3f';
            app.CrestFactorEditField.Editable = 'off';
            app.SaveImgButton = uibutton(app.UIFigure, 'push');
            app.SaveImgButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImgButtonPushed, true);
            app.SaveImgButton.Text = 'Save Img';
            app.PropertiesButton = uibutton(app.UIFigure, 'push');
            app.PropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @PropertiesButtonPushed, true);
            app.PropertiesButton.Text = 'Properties';
            app.YLimLeft = uieditfield(app.UIFigure, 'numeric');
            app.YLimLeft.ValueDisplayFormat = '%.3f';
            app.YLimLeft.ValueChangedFcn = createCallbackFcn(app, @YLimLeftValueChanged, true);
            app.XLimLULabel = uilabel(app.UIFigure);
            app.XLimLULabel.Text = 'X-Lim (L, U)';
            app.YLimLULabel = uilabel(app.UIFigure);
            app.YLimLULabel.Text = 'Y-Lim (L, U)';
            app.YLimRight = uieditfield(app.UIFigure, 'numeric');
            app.YLimRight.ValueDisplayFormat = '%.3f';
            app.YLimRight.ValueChangedFcn = createCallbackFcn(app, @YLimRightValueChanged, true);
            app.PlotLimitsLabel = uilabel(app.UIFigure);
            app.PlotLimitsLabel.Text = 'Plot Limits';
            app.ThdLab = uilabel(app.UIFigure);
            app.ThdLab.Text = 'U';
            app.THDLabel = uilabel(app.UIFigure);
            app.THDLabel.Text = 'THD:';
            app.THDEditField = uieditfield(app.UIFigure, 'numeric');
            app.THDEditField.ValueDisplayFormat = '%.3f';
            app.THDEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GDD(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end