classdef PowerQualityTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PQATUIFigure           matlab.ui.Figure
        Menu                   matlab.ui.container.Menu
        LoadFile               matlab.ui.container.Menu
        HelpMenu               matlab.ui.container.Menu
        UserGuideMenu          matlab.ui.container.Menu
        Channel1Label          matlab.ui.control.Label
        Channel1DropDown       matlab.ui.control.DropDown
        Channel2DropDownLabel  matlab.ui.control.Label
        Channel2DropDown       matlab.ui.control.DropDown
        Channel3DropDownLabel  matlab.ui.control.Label
        Channel3DropDown       matlab.ui.control.DropDown
        Channel4DropDownLabel  matlab.ui.control.Label
        Channel4DropDown       matlab.ui.control.DropDown
        Channel5DropDownLabel  matlab.ui.control.Label
        Channel5DropDown       matlab.ui.control.DropDown
        Channel6DropDownLabel  matlab.ui.control.Label
        Channel6DropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        c1AppWindow     % Analysis window for channel 1
        c2AppWindow     % Analysis window for channel 2
        c3AppWindow     % Analysis window for channel 3
        c4AppWindow     % Analysis window for channel 4
        c5AppWindow     % Analysis window for channel 5
        c6AppWindow     % Analysis window for channel 6       
    end
    
    properties (Access = private)
        signalType              % Type of signal to be read
        allData                 % Holds data that is read
        columnHeaders           % Unit of each column from data
        headerInfo              % Meta data of Data File
        columnNames             % Names for each data column
        channelNums             % # of channels;
        windowCount             % Counter for how many windows are open
        analysisWidth = 942;    % Width of analysis Window
        analysisHeigth = 499;   % Height of analysis Window
        analysisX = 530;        % X position of analysis window
        analysisY = 300;        % Y position of analysis window
        xIndent = 5;            % X indent when multiple analysis windows
        yshift = 20;            % Y shift when multiple analysis windows
    end
    
    methods (Access = private)
        
        % Enables the appropraite objects.
        function showObjects(app)
            numColumns = (size(app.columnHeaders, 2) - 1);      % Number of channels
            app.channelNums = numColumns;
            app.columnNames = strsplit(app.headerInfo{6, 1}, ',');  % Channel Names
            switch numColumns
                case 5
                    app.Channel1DropDown.Visible = 'on';
                    app.Channel2DropDown.Visible = 'on';
                    app.Channel3DropDown.Visible = 'on';
                    app.Channel4DropDown.Visible = 'on';
                    app.Channel5DropDown.Visible = 'on';
                    app.Channel6DropDown.Visible = 'off';
                    app.Channel6DropDownLabel.Visible = 'off';  
                    app.Channel1Label.Visible = 'on';
                    app.Channel2DropDownLabel.Visible = 'on';
                    app.Channel3DropDownLabel.Visible = 'on';
                    app.Channel4DropDownLabel.Visible = 'on';
                    app.Channel5DropDownLabel.Visible = 'on';
                    app.Channel1DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}};
                    app.Channel2DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}};
                    app.Channel3DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}};
                    app.Channel4DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}};
                    app.Channel5DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}};
                case 4
                    app.Channel1DropDown.Visible = 'on';
                    app.Channel2DropDown.Visible = 'on';
                    app.Channel3DropDown.Visible = 'on';
                    app.Channel4DropDown.Visible = 'on';
                    app.Channel5DropDown.Visible = 'off';
                    app.Channel1Label.Visible = 'on';
                    app.Channel2DropDownLabel.Visible = 'on';
                    app.Channel3DropDownLabel.Visible = 'on';
                    app.Channel4DropDownLabel.Visible = 'on';
                    app.Channel5DropDownLabel.Visible = 'off';
                    app.Channel6DropDown.Visible = 'off';
                    app.Channel6DropDownLabel.Visible = 'off';  
                    app.Channel1DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}};
                    app.Channel2DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}};
                    app.Channel3DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}};
                    app.Channel4DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}};
                case 3
                    app.Channel1DropDown.Visible = 'on';
                    app.Channel2DropDown.Visible = 'on';
                    app.Channel3DropDown.Visible = 'on';
                    app.Channel4DropDown.Visible = 'off';
                    app.Channel5DropDown.Visible = 'off';
                    app.Channel1Label.Visible = 'on';
                    app.Channel2DropDownLabel.Visible = 'on';
                    app.Channel3DropDownLabel.Visible = 'on';
                    app.Channel4DropDownLabel.Visible = 'off';
                    app.Channel5DropDownLabel.Visible = 'off';
                    app.Channel6DropDown.Visible = 'off';
                    app.Channel6DropDownLabel.Visible = 'off'; 
                    app.Channel1DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}};
                    app.Channel2DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}};
                    app.Channel3DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}};
                case 2
                    app.Channel1DropDown.Visible = 'on';
                    app.Channel2DropDown.Visible = 'on';
                    app.Channel3DropDown.Visible = 'off';
                    app.Channel4DropDown.Visible = 'off';
                    app.Channel5DropDown.Visible = 'off';
                    app.Channel1Label.Visible = 'on';
                    app.Channel2DropDownLabel.Visible = 'on';
                    app.Channel3DropDownLabel.Visible = 'off';
                    app.Channel4DropDownLabel.Visible = 'off';
                    app.Channel5DropDownLabel.Visible = 'off';
                    app.Channel6DropDown.Visible = 'off';
                    app.Channel6DropDownLabel.Visible = 'off'; 
                    app.Channel1DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}};
                    app.Channel2DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}};
                case 1
                    app.Channel1DropDown.Visible = 'on';
                    app.Channel2DropDown.Visible = 'off';
                    app.Channel3DropDown.Visible = 'off';
                    app.Channel4DropDown.Visible = 'off';
                    app.Channel5DropDown.Visible = 'off';
                    app.Channel1Label.Visible = 'on';
                    app.Channel2DropDownLabel.Visible = 'off';
                    app.Channel3DropDownLabel.Visible = 'off';
                    app.Channel4DropDownLabel.Visible = 'off';
                    app.Channel5DropDownLabel.Visible = 'off';
                    app.Channel6DropDown.Visible = 'off';
                    app.Channel6DropDownLabel.Visible = 'off'; 
                    app.Channel1DropDown.Items = {'<select>', app.columnNames{1, 2}};
                otherwise
                    app.Channel1DropDown.Visible = 'on';
                    app.Channel2DropDown.Visible = 'on';
                    app.Channel3DropDown.Visible = 'on';
                    app.Channel4DropDown.Visible = 'on';
                    app.Channel5DropDown.Visible = 'on';
                    app.Channel6DropDown.Visible = 'on';
                    app.Channel1Label.Visible = 'on';
                    app.Channel2DropDownLabel.Visible = 'on';
                    app.Channel3DropDownLabel.Visible = 'on';
                    app.Channel4DropDownLabel.Visible = 'on';
                    app.Channel5DropDownLabel.Visible = 'on';
                    app.Channel6DropDownLabel.Visible = 'on';  
                    app.Channel1DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}, app.columnNames{1, 7}};
                    app.Channel2DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}, app.columnNames{1, 7}};
                    app.Channel3DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}, app.columnNames{1, 7}};
                    app.Channel4DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}, app.columnNames{1, 7}};
                    app.Channel5DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}, app.columnNames{1, 7}};
                    app.Channel6DropDown.Items = {'<select>', app.columnNames{1, 2}, app.columnNames{1, 3}, app.columnNames{1, 4}, app.columnNames{1, 5}, app.columnNames{1, 6}, app.columnNames{1, 7}};
            end
        end
        
        % Function to find which channel that was chosen.
        function channelNum = findChannelNum(app, dropDownVal)
            % Get data vector
            channelName = string(dropDownVal);           
            len = length(app.columnNames);
            
            % Get channel nums
            for i = 1:len
                if isequal(channelName, app.columnNames{1, i})
                    channelNum = i;
                    break;
                end
            end
        end
        
        % May reset windowCount
        function checkReset(app)
            if (app.windowCount == app.channelNums)
                app.windowCount = 0;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Disable textboxes until file is read
            app.Channel1DropDown.Visible = 'off';
            app.Channel2DropDown.Visible = 'off';
            app.Channel3DropDown.Visible = 'off';
            app.Channel4DropDown.Visible = 'off';
            app.Channel5DropDown.Visible = 'off';
            app.Channel6DropDown.Visible = 'off';
            app.Channel1Label.Visible = 'off';
            app.Channel2DropDownLabel.Visible = 'off';
            app.Channel3DropDownLabel.Visible = 'off';
            app.Channel4DropDownLabel.Visible = 'off';
            app.Channel5DropDownLabel.Visible = 'off';
            app.Channel6DropDownLabel.Visible = 'off';
            
            % Initialize Counter
            app.windowCount = 0;
        end

        % Menu selected function: LoadFile
        function LoadFileSelected(app, event)
           % Ask User For Data Typ AC/DC/Transient
            dataParameter = questdlg('What Data Parameter?', ...
                'Data Format', ...
                'AC', 'DC', 'DC');
            % Handle Response
            switch dataParameter
                case 'AC'
                    app.signalType = 0; % AC
                case 'DC'
                    app.signalType = 1; % DC
            end
            
            try
                % Allow user to select the file
                [fileName, pathName] = uigetfile('*.*','Pick Data File');
                dataFile = [pathName, fileName];
                
                % Read in all data
                importedData = importdata(dataFile);
                             
                % Store data app
                app.allData = importedData.data();
                app.headerInfo = importedData.textdata;
                
                % Get colheaders even if < 3
                try
                    app.columnHeaders = importedData.colheaders;
                catch
                    app.columnHeaders = strsplit(cell2mat(app.headerInfo(7)));
                end
                % Process date
                showObjects(app);
            catch
                disp("Error while trying to import file...")
            end
        end

        % Value changed function: Channel1DropDown
        function Channel1DropDownValueChanged(app, event)
            value = app.Channel1DropDown.Value;
            channelNum = findChannelNum(app, value);
            channelSelect = 1;
            
            % Set window position of analysis window
            WindowPos = [app.analysisX + (app.xIndent * app.windowCount), app.analysisY - (app.yshift * app.windowCount), app.analysisWidth, app.analysisHeigth];
            app.windowCount = app.windowCount + 1;
            checkReset(app);
            
            % Run GDD GUI
            app.c1AppWindow = GDD(app, app.signalType, app.allData, app.columnHeaders, app.headerInfo, value, channelNum, channelSelect, WindowPos);   % Open GDD 1
            app.Channel1DropDown.Enable = 'off';            
        end

        % Value changed function: Channel2DropDown
        function Channel2DropDownValueChanged(app, event)
            value = app.Channel2DropDown.Value;
            channelNum = findChannelNum(app, value);
            channelSelect = 2;
            
            WindowPos = [app.analysisX + (app.xIndent * app.windowCount), app.analysisY - (app.yshift * app.windowCount), app.analysisWidth, app.analysisHeigth];
            app.windowCount = app.windowCount + 1;
            checkReset(app);
            
            app.c1AppWindow = GDD(app, app.signalType, app.allData, app.columnHeaders, app.headerInfo, value, channelNum, channelSelect, WindowPos);   % Open GDD 1
            app.Channel2DropDown.Enable = 'off';       
        end

        % Value changed function: Channel3DropDown
        function Channel3DropDownValueChanged(app, event)
            value = app.Channel3DropDown.Value;
            channelNum = findChannelNum(app, value);
            channelSelect = 3;
            
            WindowPos = [app.analysisX + (app.xIndent * app.windowCount), app.analysisY - (app.yshift * app.windowCount), app.analysisWidth, app.analysisHeigth];
            app.windowCount = app.windowCount + 1;
            checkReset(app);
            
            app.c1AppWindow = GDD(app, app.signalType, app.allData, app.columnHeaders, app.headerInfo, value, channelNum, channelSelect, WindowPos);   % Open GDD 1
            app.Channel3DropDown.Enable = 'off';       
        end

        % Value changed function: Channel4DropDown
        function Channel4DropDownValueChanged(app, event)
            value = app.Channel4DropDown.Value;
            channelNum = findChannelNum(app, value);
            channelSelect = 4;
            
            WindowPos = [app.analysisX + (app.xIndent * app.windowCount), app.analysisY - (app.yshift * app.windowCount), app.analysisWidth, app.analysisHeigth];
            app.windowCount = app.windowCount + 1;
            checkReset(app);
            
            app.c1AppWindow = GDD(app, app.signalType, app.allData, app.columnHeaders, app.headerInfo, value, channelNum, channelSelect, WindowPos);   % Open GDD 1
            app.Channel4DropDown.Enable = 'off';     
        end

        % Value changed function: Channel5DropDown
        function Channel5DropDownValueChanged(app, event)
            value = app.Channel5DropDown.Value;
            channelNum = findChannelNum(app, value);
            channelSelect = 5;
            
            WindowPos = [app.analysisX + (app.xIndent * app.windowCount), app.analysisY - (app.yshift * app.windowCount), app.analysisWidth, app.analysisHeigth];
            app.windowCount = app.windowCount + 1;
            checkReset(app);
            
            app.c1AppWindow = GDD(app, app.signalType, app.allData, app.columnHeaders, app.headerInfo, value, channelNum, channelSelect, WindowPos);   % Open GDD 1
            app.Channel5DropDown.Enable = 'off';    
        end

        % Value changed function: Channel6DropDown
        function Channel6DropDownValueChanged(app, event)
            value = app.Channel6DropDown.Value;
            if ~isequal(value, '')
                channelNum = findChannelNum(app, value);
                channelSelect = 6;
                
                WindowPos = [app.analysisX + (app.xIndent * app.windowCount), app.analysisY - (app.yshift * app.windowCount), app.analysisWidth, app.analysisHeigth];
                app.windowCount = app.windowCount + 1;
                checkReset(app);
                
                app.c1AppWindow = GDD(app, app.signalType, app.allData, app.columnHeaders, app.headerInfo, value, channelNum, channelSelect, WindowPos);   % Open GDD 1
                app.Channel6DropDown.Enable = 'off';    
            end  
        end

        % Close request function: PQATUIFigure
        function PQATUIFigureCloseRequest(app, event)
            delete(app);    % Delete app
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PQATUIFigure = uifigure;
            app.PQATUIFigure.Name = 'PQAT';
            app.PQATUIFigure.CloseRequestFcn = createCallbackFcn(app, @PQATUIFigureCloseRequest, true);
            app.Menu = uimenu(app.PQATUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadFile = uimenu(app.Menu);
            app.LoadFile.MenuSelectedFcn = createCallbackFcn(app, @LoadFileSelected, true);
            app.LoadFile.Text = 'Load File';
            app.HelpMenu = uimenu(app.PQATUIFigure);
            app.HelpMenu.Text = 'Help';
            app.UserGuideMenu = uimenu(app.HelpMenu);
            app.UserGuideMenu.Text = 'User Guide';
            app.Channel1Label = uilabel(app.PQATUIFigure);
            app.Channel1Label.Text = 'Channel 1:';
            app.Channel1DropDown = uidropdown(app.PQATUIFigure);
            app.Channel1DropDown.Items = {'<select>'};
            app.Channel1DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel1DropDownValueChanged, true);
            app.Channel1DropDown.Value = '<select>';
            app.Channel2DropDownLabel = uilabel(app.PQATUIFigure);
            app.Channel2DropDownLabel.Text = 'Channel 2:';
            app.Channel2DropDown = uidropdown(app.PQATUIFigure);
            app.Channel2DropDown.Items = {'<select>'};
            app.Channel2DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel2DropDownValueChanged, true);
            app.Channel2DropDown.Value = '<select>';
            app.Channel3DropDownLabel = uilabel(app.PQATUIFigure);
            app.Channel3DropDownLabel.Text = 'Channel 3:';
            app.Channel3DropDown = uidropdown(app.PQATUIFigure);
            app.Channel3DropDown.Items = {'<select>'};
            app.Channel3DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel3DropDownValueChanged, true);
            app.Channel3DropDown.Value = '<select>';
            app.Channel4DropDownLabel = uilabel(app.PQATUIFigure);
            app.Channel4DropDownLabel.Text = 'Channel 4:';
            app.Channel4DropDown = uidropdown(app.PQATUIFigure);
            app.Channel4DropDown.Items = {'<select>'};
            app.Channel4DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel4DropDownValueChanged, true);
            app.Channel4DropDown.Value = '<select>';
            app.Channel5DropDownLabel = uilabel(app.PQATUIFigure);
            app.Channel5DropDownLabel.Text = 'Channel 5:';
            app.Channel5DropDown = uidropdown(app.PQATUIFigure);
            app.Channel5DropDown.Items = {'<select>'};
            app.Channel5DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel5DropDownValueChanged, true);
            app.Channel5DropDown.Value = '<select>';
            app.Channel6DropDownLabel = uilabel(app.PQATUIFigure);
            app.Channel6DropDownLabel.Text = 'Channel 6:';
            app.Channel6DropDown = uidropdown(app.PQATUIFigure);
            app.Channel6DropDown.Items = {'<select>'};
            app.Channel6DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel6DropDownValueChanged, true);
            app.Channel6DropDown.Value = '<select>';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PowerQualityTool

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PQATUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PQATUIFigure)
        end
    end
end