classdef powerInt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerInterruptUIFigure          matlab.ui.Figure
        TimesInterruptedEditFieldLabel  matlab.ui.control.Label
        TimesInterruptedEditField       matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        VoltageChannelDropDownLabel     matlab.ui.control.Label
        VoltageChannelDropDown          matlab.ui.control.DropDown
        CurrentChannelDropDownLabel     matlab.ui.control.Label
        CurrentChannelDropDown          matlab.ui.control.DropDown
        InterruptTable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainApp         % Handle to GDD app
        allData         % Handle to all data
        columnHeaders   % Unit for each column
        headerInfo      % MetaData of file
        startIndex      % Start index of data subset
        stopIndex       % Stop index of data subset
        colNames        % Names of each column
        sampleFreq      % Sampling frequency % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, allData, columnHeaders, headerInfo, startIndex, stopIndex, sampleFreq)
            % Initialize input args
            app.mainApp = mainApp;
            app.allData = allData;
            app.columnHeaders = columnHeaders;
            app.headerInfo = headerInfo;
            app.startIndex = startIndex;
            app.stopIndex = stopIndex;
            app.sampleFreq = sampleFreq;
            
            % GUI INIT
            numColumns = (size(columnHeaders, 2) - 1);      % Number of channels
            app.colNames = strsplit(headerInfo{6, 1}, ',');  % Channel Names      
            if numColumns == 6
                app.VoltageChannelDropDown.Items = {'', app.colNames{1, 2}, app.colNames{1, 3}, app.colNames{1, 4}, app.colNames{1, 5}, app.colNames{1, 6}, app.colNames{1, 7}};
                app.CurrentChannelDropDown.Items = {'', app.colNames{1, 2}, app.colNames{1, 3}, app.colNames{1, 4}, app.colNames{1, 5}, app.colNames{1, 6}, app.colNames{1, 7}};
            elseif numColumns == 4
                app.VoltageChannelDropDown.Items = {'', app.colNames{1, 2}, app.colNames{1, 3}, app.colNames{1, 4}, app.colNames{1, 5}};
                app.CurrentChannelDropDown.Items = {'', app.colNames{1, 2}, app.colNames{1, 3}, app.colNames{1, 4}, app.colNames{1, 5}};
            else
                app.VoltageChannelDropDown.Items = {'', app.colNames{1, 2}, app.colNames{1, 3}};
                app.CurrentChannelDropDown.Items = {'', app.colNames{1, 2}, app.colNames{1, 3}};
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          
            % Get data vectors
            voltageV = string(app.VoltageChannelDropDown.Value);
            currentV = string(app.CurrentChannelDropDown.Value);
            
            colNums = [0,0];            
            len = length(app.colNames);
            
            % Get channel nums
            for i = 1:len
                if isequal(voltageV, app.colNames{1, i})
                    colNums(1) = i;
                end
                if isequal(currentV, app.colNames{1, i})
                    colNums(2) = i;
                end
            end
            
            % Data vectors
            voltageData = app.allData(:, colNums(1));
            currentData = app.allData(:, colNums(2));
            timeData = app.allData(:, 1);

            % Get subset
            voltageData = voltageData(app.startIndex:app.stopIndex);
            currentData = currentData(app.startIndex:app.stopIndex);
            timeData = timeData(app.startIndex:app.stopIndex);
            
            % Length of data in file
            n = length(timeData);
            
            % sampling F
            fs = app.sampleFreq;
            
            % Creating a waveform of instantaneous power
            avgP = zeros(1,n);
            for k = 1:n
               avgP(k) = voltageData(k) * currentData(k);
            end
            
            % Finding a cycle time that will be the min length for power interrupt
            x = voltageData;
            upZeroCrossing = find(x(1:end-1) <= 0 & x(2:end) > 0);
            PeakDistance = upZeroCrossing(2)-upZeroCrossing(1);
            
            powerInt = zeros(1,n);
            maxRealPower = max(avgP);
            
            % Making a vector of every point that is below 25% of max instantaneous
            % power. 1 if it is below threshold and 0 if not.
            for k = 1:length(avgP)
               if (avgP(k) <= (maxRealPower * 0.25))
                   powerInt(k) = 1;
               end
            end
            
            % Looks at powerInt vector and determines the start locations
            % and durations of each time power is less than the 25% threshold value
            int_duration = [];
            count = 0;
            int_start = [];
            for k = 1:length(powerInt)
                if powerInt(k) == 1
                    count = count + 1;
                elseif (k ~= 1 && powerInt(k-1) == 1 && powerInt(k) == 0)
                    int_duration = [int_duration ; count];
                    int_start = [int_start ; (k - count)];
                    count = 0;
                end
                if (k ~= 1 && k == (length(powerInt)) && powerInt(length(powerInt)) == 1)
                    int_duration = [int_duration ; count];
                    int_start = [int_start ; (k - count)];
                end
            end
    
            % Comparing all potential power interrupts with the length of 1 cycle
            % if it is >= cycle length then it will be considered a power interrupt
            % and will be put in anew vector 'isPowerInt'.
            isPowerInt = [];
            for k = 1:length(int_duration)
                if (int_duration(k)) >= PeakDistance
                    isPowerInt = [isPowerInt ; int_start(k)./fs];
                end
            end

            % Prints the results in the appropriate tables and text fields.
            if (~isempty(isPowerInt))
                app.InterruptTable.Data = (isPowerInt.');
            end
            app.TimesInterruptedEditField.Value = length(isPowerInt);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PowerInterruptUIFigure = uifigure;
            app.PowerInterruptUIFigure.Name = 'Power Interrupt';
            app.TimesInterruptedEditFieldLabel = uilabel(app.PowerInterruptUIFigure);
            app.TimesInterruptedEditFieldLabel.Text = 'Times Interrupted:';
            app.TimesInterruptedEditField = uieditfield(app.PowerInterruptUIFigure, 'numeric');
            app.CalculateButton = uibutton(app.PowerInterruptUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.VoltageChannelDropDownLabel = uilabel(app.PowerInterruptUIFigure);
            app.VoltageChannelDropDownLabel.Text = 'Voltage Channel';
            app.VoltageChannelDropDown = uidropdown(app.PowerInterruptUIFigure);
            app.CurrentChannelDropDownLabel = uilabel(app.PowerInterruptUIFigure);
            app.CurrentChannelDropDownLabel.Text = 'Current Channel';
            app.CurrentChannelDropDown = uidropdown(app.PowerInterruptUIFigure);
            app.InterruptTable = uitable(app.PowerInterruptUIFigure);
            app.InterruptTable.ColumnName = {''};
            app.InterruptTable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = powerInt(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PowerInterruptUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerInterruptUIFigure)
        end
    end
end